classdef Voice_Spectrum_Analyzer_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FsField                     matlab.ui.control.NumericEditField
        SamplingFreqHzLabel         matlab.ui.control.Label
        TmaxField                   matlab.ui.control.NumericEditField
        MaxTimeseLabel              matlab.ui.control.Label
        STOPButton                  matlab.ui.control.Button
        VoiceSpectrumAnalyzerLabel  matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        SpectrumTypeDropDown        matlab.ui.control.DropDown
        SpectrumTypeLabel           matlab.ui.control.Label
        FrequencyDomainPlot         matlab.ui.control.UIAxes
        TimeDomainPlot              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Recorder % Audio recorder object
    end
    
    methods (Access = private)
        
     function processRecording(app)
    % Retrieve audio data
    audioSignal = getaudiodata(app.Recorder);
    Fs = app.FsField.Value; % Sampling frequency

    % Time-Domain Plot
    t = (0:length(audioSignal)-1) / Fs;
    plot(app.TimeDomainPlot, t, audioSignal);
    xlabel(app.TimeDomainPlot, 'Time (s)');
    ylabel(app.TimeDomainPlot, 'Amplitude');
    title(app.TimeDomainPlot, 'Time-Domain Signal');
    grid(app.TimeDomainPlot, 'on');

    % Frequency-Domain Analysis
    N = length(audioSignal); % Signal length
    fftSignal = fft(audioSignal); % Perform FFT
    frequencies = (0:N-1)*(Fs/N); % Frequency vector
    fftSignal = fftSignal(1:N/2); % Positive frequencies only
    frequencies = frequencies(1:N/2);

    % User-selected spectrum type
    spectrumType = app.SpectrumTypeDropDown.Value;

    % Compute Spectrum
    switch spectrumType
        case 'Amplitude'
            spectrumData = abs(fftSignal);
            ylabelText = 'Amplitude';
            xlabelText = 'Amplitude';
        case 'Phase'
            spectrumData = angle(fftSignal);
            ylabelText = 'Phase (radians)';
            xlabelText = 'Phase (radians)';
        case 'Energy'
            spectrumData = abs(fftSignal).^2;
            ylabelText = 'Energy';
            xlabelText = 'Energy';
        otherwise
            spectrumData = abs(fftSignal);
            ylabelText = 'Amplitude';
            xlabelText = 'Amplitude';
    end

    % Update Frequency Plot
    plot(app.FrequencyDomainPlot, frequencies, spectrumData);
    xlabel(app.FrequencyDomainPlot, 'Frequency (Hz)');
    ylabel(app.FrequencyDomainPlot, ylabelText);
    title(app.FrequencyDomainPlot, [spectrumType, ' Spectrum']);
    grid(app.FrequencyDomainPlot, 'on');

    % Enable Interactive Control
    zoom(app.FrequencyDomainPlot, 'on'); % Enable zooming
    pan(app.FrequencyDomainPlot, 'on');  % Enable panning
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        Tmax = app.TmaxField.Value;
        Fs = app.FsField.Value;

        % Create audio recorder object
        app.Recorder = audiorecorder(Fs, 16, 1); % 16-bit, mono
        recordblocking(app.Recorder, Tmax); % Start and stop automatically after Tmax seconds

        % Retrieve and process audio data
        processRecording(app);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
        if isrecording(app.Recorder)
            stop(app.Recorder); % Stop recording
        end
        % Process the recorded audio
        processRecording(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.TimeDomainPlot = uiaxes(app.Panel);
            title(app.TimeDomainPlot, 'Time-Domain Signal')
            xlabel(app.TimeDomainPlot, 'Time (s)')
            ylabel(app.TimeDomainPlot, 'Amplitude')
            zlabel(app.TimeDomainPlot, 'Z')
            app.FrequencyDomainPlot = uiaxes(app.Panel);
            title(app.FrequencyDomainPlot, 'Spectrum')
            xlabel(app.FrequencyDomainPlot, 'Frequancy (Hz)')
            zlabel(app.FrequencyDomainPlot, 'Z')
            app.SpectrumTypeLabel = uilabel(app.Panel);
            app.SpectrumTypeLabel.Text = 'Spectrum Type';
            app.SpectrumTypeDropDown = uidropdown(app.Panel);
            app.SpectrumTypeDropDown.Items = {'Amplitude', 'Phase', 'Energy'};
            app.SpectrumTypeDropDown.Value = 'Amplitude';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.VoiceSpectrumAnalyzerLabel = uilabel(app.UIFigure);
            app.VoiceSpectrumAnalyzerLabel.Text = 'Voice Spectrum Analyzer';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MaxTimeseLabel = uilabel(app.UIFigure);
            app.MaxTimeseLabel.Text = 'Max Time (s)';
            app.TmaxField = uieditfield(app.UIFigure, 'numeric');
            app.TmaxField.ValueDisplayFormat = '%.0f';
            app.TmaxField.Value = 5;
            app.SamplingFreqHzLabel = uilabel(app.UIFigure);
            app.SamplingFreqHzLabel.Text = 'Sampling Freq (Hz)';
            app.FsField = uieditfield(app.UIFigure, 'numeric');
            app.FsField.ValueDisplayFormat = '%.0f';
            app.FsField.Value = 44000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voice_Spectrum_Analyzer_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end